classdef Interfaz_Grafica < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        UIAxes                    matlab.ui.control.UIAxes
        ImportararchivosButton    matlab.ui.control.Button
        CalcularParametrosButton  matlab.ui.control.Button
        UITable                   matlab.ui.control.Table
    end

    methods (Access = private)

        % Button pushed function: ImportararchivosButton
        function ImportararchivosButtonPushed(app, event)
               signal = importarDataSet();
        end

        % Callback function
        function QuearchivosqueresimportarEditFieldValueChanging(app, event)
            changingValue = event.Value;
            display(changingValue);
        end

        % Button pushed function: CalcularParametrosButton
        function CalcularParametrosButtonPushed(app, event)
            Info = audioinfo(signal);
            Fs = Info.SampleRate;
            [t_plot,w,h_t_MAF,t_plot_shroeder,z] = suavizadoconlim(signal,Fs);
            disp ([t_plot,w,h_t_MAF,t_plot_shroeder,z]) ;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [0 1];
            app.UIAxes.ZLim = [0 1];
            app.UIAxes.CLim = [0 1];
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.UIAxes.ZTick = [0 0.5 1];
            app.ImportararchivosButton = uibutton(app.UIFigure, 'push');
            app.ImportararchivosButton.ButtonPushedFcn = createCallbackFcn(app, @ImportararchivosButtonPushed, true);
            app.ImportararchivosButton.Text = 'Importar archivos';
            app.CalcularParametrosButton = uibutton(app.UIFigure, 'push');
            app.CalcularParametrosButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularParametrosButtonPushed, true);
            app.CalcularParametrosButton.Text = 'Calcular Parametros';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'EDT'; 'T10'; 'T20'; 'T30'; 'D50'; 'C80'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Interfaz_Grafica

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end